classdef BTL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        QutrnhPanel          matlab.ui.container.Panel
        TextArea             matlab.ui.control.TextArea
        Panel                matlab.ui.container.Panel
        AddButton            matlab.ui.control.Button
        CalculateButton      matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        ngnhitButton         matlab.ui.control.Button
        ngpButton            matlab.ui.control.Button
        ngtchButton          matlab.ui.control.Button
        NhpPanel             matlab.ui.container.Panel
        V1SliderLabel        matlab.ui.control.Label
        p1SliderLabel        matlab.ui.control.Label
        V0SliderLabel        matlab.ui.control.Label
        p0SliderLabel        matlab.ui.control.Label
        n0SliderLabel        matlab.ui.control.Label
        V1Slider             matlab.ui.control.Slider
        EditField_5          matlab.ui.control.NumericEditField
        p1Slider             matlab.ui.control.Slider
        EditField_4          matlab.ui.control.NumericEditField
        V0Slider             matlab.ui.control.Slider
        EditField_3          matlab.ui.control.NumericEditField
        p0Slider             matlab.ui.control.Slider
        EditField_2          matlab.ui.control.NumericEditField
        n0Slider             matlab.ui.control.Slider
        EditField            matlab.ui.control.NumericEditField
        TI13XCNHCNGCAHTRONGCCQUTRNHCNBNGVVTHBIUDINCCQUTRNHTRNGINpVLabel  matlab.ui.control.Label
        CN01Nhm1Label        matlab.ui.control.Label
        KtquPanel            matlab.ui.container.Panel
        WEditField           matlab.ui.control.NumericEditField
        TEditField_2Label_2  matlab.ui.control.Label
        TEditField           matlab.ui.control.NumericEditField
        TEditField_2Label    matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: n0Slider
        function n0SliderValueChanged(app, event)
            value = app.n0Slider.Value;
            app.EditField.Value = value;
        end

        % Value changed function: p0Slider
        function p0SliderValueChanged(app, event)
            value = app.p0Slider.Value;
            app.EditField_2.Value = value;
        end

        % Value changed function: V0Slider
        function V0SliderValueChanged(app, event)
            value = app.V0Slider.Value;
            app.EditField_3.Value = value;
        end

        % Value changed function: p1Slider
        function p1SliderValueChanged(app, event)
            value = app.p1Slider.Value;
            app.EditField_4.Value = value;
        end

        % Value changed function: V1Slider
        function V1SliderValueChanged(app, event)
            value = app.V1Slider.Value;
            app.EditField_5.Value = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if (app.EditField.Value>=100)
                app.n0Slider.Value = 100;
            else
                app.n0Slider.Value = app.EditField.Value;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if (app.EditField_2.Value>=100)
                app.p0Slider.Value = 100;
            else
                app.p0Slider.Value = app.EditField_2.Value;
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if (app.EditField_3.Value>=100)
                app.V0Slider.Value = 100;
            else
                app.V0Slider.Value = app.EditField_3.Value;
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            if (app.EditField_4.Value>=100)
                app.p1Slider.Value = 100;
            else
                app.p1Slider.Value = app.EditField_4.Value;
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            if (app.EditField_5.Value>=100)
                app.V1Slider.Value = 100;
            else
                app.V1Slider.Value = app.EditField_5.Value;
            end
        end

        % Button pushed function: ngtchButton
        function ngtchButtonPushed(app, event)
            app.ngtchButton.BackgroundColor=[0.07, 0.62, 1.00];
            app.ResetButton.Enable='on';
            app.ngnhitButton.Enable='off';
            app.ngpButton.Enable='off';
            app.NhpPanel.Enable='on';
            app.KtquPanel.Enable='on';
            app.V1Slider.Enable='off';
            app.V1SliderLabel.Enable='off';
            app.p1Slider.Enable='on';
            app.p1SliderLabel.Enable='on';
            app.EditField_4.Enable='on';
            app.EditField_5.Enable='off';
            app.TEditField.Enable='off';
            app.AddButton.Enable='off';
            app.CalculateButton.Enable='on';
            message = 'Đẳng tích';
            if strcmp(app.TextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.TextArea.Value(:)', {message}];
            end
            app.TextArea.Value = message_disp;
        end

        % Button pushed function: ngnhitButton
        function ngnhitButtonPushed(app, event)
            app.ngnhitButton.BackgroundColor=[0.07, 0.62, 1.00];
            app.ResetButton.Enable='on';
            app.ngtchButton.Enable='off';
            app.ngpButton.Enable='off';
            app.NhpPanel.Enable='on';
            app.KtquPanel.Enable='on';
            app.p1Slider.Enable='off';
            app.p1SliderLabel.Enable='off';
            app.V1Slider.Enable='on';
            app.V1SliderLabel.Enable='on';
            app.TEditField.Enable='on';
            app.EditField_5.Enable='on';
            app.EditField_4.Enable='off';
            app.AddButton.Enable='off';
            app.CalculateButton.Enable='on'; 
            message = 'Đẳng nhiệt';
            if strcmp(app.TextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.TextArea.Value(:)', {message}];
            end
            app.TextArea.Value = message_disp;
        end

        % Button pushed function: ngpButton
        function ngpButtonPushed(app, event)
            app.ngpButton.BackgroundColor=[0.07, 0.62, 1.00];            
            app.ResetButton.Enable='on';
            app.ngtchButton.Enable='off';
            app.ngnhitButton.Enable='off';
            app.NhpPanel.Enable='on';
            app.KtquPanel.Enable='on';
            app.p1Slider.Enable='off';
            app.p1SliderLabel.Enable='off';
            app.EditField_4.Enable='off';
            app.TEditField.Enable='off';
            app.V1Slider.Enable='on';
            app.V1SliderLabel.Enable='on';
            app.EditField_5.Enable='on';
            app.AddButton.Enable='off';
            app.CalculateButton.Enable='on';
            message = 'Đẳng áp';
            if strcmp(app.TextArea.Value,'')
                message_disp = message;
            else
                message_disp = [app.TextArea.Value(:)', {message}];
            end
            app.TextArea.Value = message_disp;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.n0 = app.EditField.Value;
            app.T=n0*2;
            app.TEditField.Value = T;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ngtchButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngnhitButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngpButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngtchButton.Enable='on';
            app.ngpButton.Enable='on';
            app.ngnhitButton.Enable='on';
            app.ResetButton.Enable='off';
            app.NhpPanel.Enable='off';
            app.n0Slider.Value=0;
            app.n0Slider.Enable='on';
            app.p0Slider.Value=0;
            app.p0Slider.Enable='on';
            app.V0Slider.Value=0;
            app.V0Slider.Enable='on';
            app.p1Slider.Value=0;
            app.p1Slider.Enable='on';
            app.V1Slider.Value=0;
            app.V1Slider.Enable='on';
            app.EditField.Value=0;
            app.EditField.Enable='on';
            app.EditField_2.Value=0;
            app.EditField_2.Enable='on';
            app.EditField_3.Value=0;
            app.EditField_3.Enable='on';
            app.EditField_4.Value=0;
            app.EditField_4.Enable='on';
            app.EditField_5.Value=0;
            app.EditField_5.Enable='on';
            app.TEditField.Value=0;
            app.KtquPanel.Enable='off';     
            app.AddButton.Enable='off';
            app.CalculateButton.Enable='off';
            app.WEditField.Value=0;
            app.TextArea.Value='';
            cla(app.UIAxes)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            n0=app.EditField.Value;
            p0=app.EditField_2.Value;
            V0=app.EditField_3.Value;
            P1=app.EditField_4.Value;
            V1=app.EditField_5.Value;
            R=8.31446;
            if (app.ngtchButton.Enable==1)
                app.WEditField.Value = app.WEditField.Value + 0;
                hold (app.UIAxes,'on');
                if (P1>p0) 
                    x =p0:P1;
                else
                    x = P1:p0;
                end
                y =x-x+V0;
                plot(app.UIAxes,y,x,'r');                
            end
            if (app.ngnhitButton.Enable==1)
                if (n0==0)
                    app.TEditField.Value = inf;
                else
                    app.TEditField.Value=(p0*V0)/(n0*R);
                    T=app.TEditField.Value;
                    app.WEditField.Value = app.WEditField.Value + n0*R*T*log(V1/V0);
                    hold (app.UIAxes,'on');
                    if (V1>V0) 
                        x =V0:V1;
                        xi = V0:0.0000001:V1; %Xi sẽ đi từ V0 đến V1 với khoảng tăng là 0.0000001
                    else
                        x = V1:V0;
                        xi = V1:0.0000001:V0; %Xi sẽ đi từ V1 đến V0 với khoảng tăng là 0.0000001
                    end
                    y=(T*n0*R)./x;
                    P1=p0*V0./V1;
                    vid=interp1(x,y,xi,'spline'); %Tạo hàm vid có đường cong không ngắt quãng
                                                  % với hai hàm x, y và khoảng biến thiên Xi.
                                                  
                    plot(app.UIAxes,xi,vid,'r');  %Vẽ đồ thị với khoảng biến thiên Xi và hàm vid                  
                    app.EditField_4.Value=P1;
                    if (app.EditField_4.Value>=100)
                        app.p1Slider.Value = 100;
                    else
                        app.p1Slider.Value = app.EditField_4.Value;
                    end
                end
            end
            if (app.ngpButton.Enable==1)
                app.WEditField.Value = app.WEditField.Value + p0*(V1-V0);
                hold (app.UIAxes,'on');
                if (V1>V0) 
                    x =V0:V1;
                else
                    x = V1:V0;
                end
                y =x-x+p0;
                plot(app.UIAxes,x,y,'r');
            end                  
            app.AddButton.Enable='on';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if (app.ngtchButton.Enable==1)                
                app.EditField_2.Value = app.EditField_4.Value;
                if (app.EditField_4.Value>=100)
                    app.p0Slider.Value = 100;
                else
                    app.p0Slider.Value = app.EditField_4.Value;
                end
            end
            if (app.ngnhitButton.Enable==1)
                app.EditField_3.Value = app.EditField_5.Value;
                if (app.EditField_5.Value>=100)
                    app.p1Slider.Value = 100;
                else
                    app.p1Slider.Value = app.EditField_5.Value;
                end 

                app.EditField_2.Value = app.EditField_4.Value;
                if (app.EditField_4.Value>=100)
                    app.p0Slider.Value = 100;
                else
                    app.p0Slider.Value = app.EditField_4.Value;
                end  
            end
            if (app.ngpButton.Enable==1)
                app.EditField_3.Value = app.EditField_5.Value;
                if (app.EditField_5.Value>=100)
                    app.p1Slider.Value = 100;
                else
                    app.p1Slider.Value = app.EditField_5.Value;
                end 
            end
            app.ngtchButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngnhitButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngpButton.BackgroundColor=[0.96, 0.96, 0.96];
            app.ngtchButton.Enable='on';
            app.ngpButton.Enable='on';
            app.ngnhitButton.Enable='on';
            app.ResetButton.Enable='on';
            app.NhpPanel.Enable='off';
            app.p1Slider.Value=0;
            app.V1Slider.Value=0;
            app.EditField_4.Value=0;
            app.EditField_5.Value=0;
            app.TEditField.Value=0;
            app.KtquPanel.Enable='off';
            app.CalculateButton.Enable='off';
            app.n0Slider.Enable='off';
            app.p0Slider.Enable='off';
            app.V0Slider.Enable='off';
            app.EditField.Enable='off';
            app.EditField_2.Enable='off';
            app.EditField_3.Enable='off';       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KtquPanel = uipanel(app.UIFigure);
            app.KtquPanel.Enable = 'off';
            app.KtquPanel.Title = 'Kết quả';
            app.UIAxes = uiaxes(app.KtquPanel);
            title(app.UIAxes, 'Đồ thị p, V')
            xlabel(app.UIAxes, 'V (m3)')
            ylabel(app.UIAxes, 'p (Pa)')
            zlabel(app.UIAxes, 'Z')
            app.TEditField_2Label = uilabel(app.KtquPanel);
            app.TEditField_2Label.Text = 'T';
            app.TEditField = uieditfield(app.KtquPanel, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Editable = 'off';
            app.TEditField_2Label_2 = uilabel(app.KtquPanel);
            app.TEditField_2Label_2.Text = 'W';
            app.WEditField = uieditfield(app.KtquPanel, 'numeric');
            app.WEditField.Editable = 'off';
            app.CN01Nhm1Label = uilabel(app.UIFigure);
            app.CN01Nhm1Label.Text = 'CN01 - Nhóm 1';
            app.TI13XCNHCNGCAHTRONGCCQUTRNHCNBNGVVTHBIUDINCCQUTRNHTRNGINpVLabel = uilabel(app.UIFigure);
            app.TI13XCNHCNGCAHTRONGCCQUTRNHCNBNGVVTHBIUDINCCQUTRNHTRNGINpVLabel.Text = {'ĐỀ TÀI 13: XÁC ĐỊNH CÔNG CỦA HỆ TRONG CÁC QUÁ TRÌNH CÂN BẰNG'; 'VÀ VẼ ĐỒ THỊ BIỂU DIỄN CÁC QUÁ TRÌNH TRÊN GIÃN ĐỒ (p, V)'};
            app.NhpPanel = uipanel(app.UIFigure);
            app.NhpPanel.Enable = 'off';
            app.NhpPanel.Title = 'Nhập';
            app.EditField = uieditfield(app.NhpPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.n0Slider = uislider(app.NhpPanel);
            app.n0Slider.ValueChangedFcn = createCallbackFcn(app, @n0SliderValueChanged, true);
            app.EditField_2 = uieditfield(app.NhpPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.p0Slider = uislider(app.NhpPanel);
            app.p0Slider.ValueChangedFcn = createCallbackFcn(app, @p0SliderValueChanged, true);
            app.EditField_3 = uieditfield(app.NhpPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.V0Slider = uislider(app.NhpPanel);
            app.V0Slider.ValueChangedFcn = createCallbackFcn(app, @V0SliderValueChanged, true);
            app.EditField_4 = uieditfield(app.NhpPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.p1Slider = uislider(app.NhpPanel);
            app.p1Slider.ValueChangedFcn = createCallbackFcn(app, @p1SliderValueChanged, true);
            app.EditField_5 = uieditfield(app.NhpPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.V1Slider = uislider(app.NhpPanel);
            app.V1Slider.ValueChangedFcn = createCallbackFcn(app, @V1SliderValueChanged, true);
            app.n0SliderLabel = uilabel(app.NhpPanel);
            app.n0SliderLabel.Text = 'n0';
            app.p0SliderLabel = uilabel(app.NhpPanel);
            app.p0SliderLabel.Text = 'p0';
            app.V0SliderLabel = uilabel(app.NhpPanel);
            app.V0SliderLabel.Text = 'V0';
            app.p1SliderLabel = uilabel(app.NhpPanel);
            app.p1SliderLabel.Text = 'p1';
            app.V1SliderLabel = uilabel(app.NhpPanel);
            app.V1SliderLabel.Text = 'V1';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ngtchButton = uibutton(app.Panel, 'push');
            app.ngtchButton.ButtonPushedFcn = createCallbackFcn(app, @ngtchButtonPushed, true);
            app.ngtchButton.Text = 'Đẳng tích';
            app.ngpButton = uibutton(app.Panel, 'push');
            app.ngpButton.ButtonPushedFcn = createCallbackFcn(app, @ngpButtonPushed, true);
            app.ngpButton.Text = 'Đẳng áp';
            app.ngnhitButton = uibutton(app.Panel, 'push');
            app.ngnhitButton.ButtonPushedFcn = createCallbackFcn(app, @ngnhitButtonPushed, true);
            app.ngnhitButton.Text = 'Đẳng nhiệt';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.AddButton = uibutton(app.Panel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.QutrnhPanel = uipanel(app.UIFigure);
            app.QutrnhPanel.Title = 'Quá trình';
            app.TextArea = uitextarea(app.QutrnhPanel);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BTL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end